classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        TrainButton             matlab.ui.control.Button
        TestButton              matlab.ui.control.Button
        CloseButton             matlab.ui.control.Button
        FacialRecognitionLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        projectimg = [];
        folder = '';
        m = [];
        eigenfaces = [];% Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: TestButton
        function testimagefn(app, event)
            path = uigetdir;
            app.UIFigure.Visible = 'off';     % Toggles figure visible.  These two lines of code work-around an issue whether the figure is sent to the background.
            app.UIFigure.Visible = 'on';  
            filter = '*.pgm';
            selectedFile = uigetfile(fullfile(path,filter));
            testimg = fullfile(path,selectedFile);
            %%imshow(selectedFile,'parent',app.UIAxes);
            testm(app.projectimg,app.eigenfaces,testimg,app.folder,app.m);
           
        end

        % Button pushed function: TrainButton
        function trainfn(app, event)
            [app.projectimg ,app.eigenfaces, app.folder,app.m ] = trainm();
        end

        % Button pushed function: CloseButton
        function closefn(app, event)
            delete(app);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TrainButton = uibutton(app.UIFigure, 'push');
            app.TrainButton.ButtonPushedFcn = createCallbackFcn(app, @trainfn, true);
            app.TrainButton.Text = {'Train'; ''};
            app.TestButton = uibutton(app.UIFigure, 'push');
            app.TestButton.ButtonPushedFcn = createCallbackFcn(app, @testimagefn, true);
            app.TestButton.Text = 'Test';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @closefn, true);
            app.CloseButton.Text = 'Close';
            app.FacialRecognitionLabel = uilabel(app.UIFigure);
            app.FacialRecognitionLabel.Text = {'Facial  Recognition'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end